classdef AppImg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Menu          matlab.ui.container.Menu
        LoadMenu      matlab.ui.container.Menu
        GridLayout    matlab.ui.container.GridLayout
        LabelsPanel   matlab.ui.container.Panel
        GridLayout2   matlab.ui.container.GridLayout
        AddLabelBtn   matlab.ui.control.Button
        Label         matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
        ContextMenu   matlab.ui.container.ContextMenu
        ContextMenu2  matlab.ui.container.ContextMenu
        Menu_3        matlab.ui.container.Menu
        Menu2_2       matlab.ui.container.Menu
    end

    properties (Access = private)
        % GridLayout2
        btnSize = 25;
        labelSize = 25;
    end
    properties (Access = public)
        currentImage;

        newLabel; % store return value for the dialogbox
    end
    methods (Access = private)
        function showImage(app, imagePath)
            % hack to force matlab to scale the image in order to fit
            app.UIFigure.Position(3:4) = [app.UIFigure.Position(3)+1, app.UIFigure.Position(4)+1];
            drawnow 

            app.currentImage = imshow(imagePath, 'Parent',app.UIAxes);
            app.currentImage.HitTest = 'off';
            
            app.UIAxes.Position = [0 0 app.UIFigure.Position(3:4)];
            app.UIAxes.XLim = [0 app.currentImage.XData(2)];
            app.UIAxes.YLim = [0 app.currentImage.YData(2)];
        
            % restore the original dimensions
            app.UIFigure.Position(3:4) = [app.UIFigure.Position(3)-1, app.UIFigure.Position(4)-1];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Fill figure with axes and remove tick labels
            app.UIAxes.Position = [0 0 app.UIFigure.Position(3:4)];
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            % app.Label.Text = app.labelText;
            axtoolbar(app.UIAxes,{"pan","zoomin","zoomout",'restoreview'});
   
            app.showImage('pig.jpg');

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            [x,y,rgb] = getMouseXY(app);
            
            if x == x % is not nan
                row = ceil(y);
                col = ceil(x);
                app.Label.Text = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)', row, col, rgb(1), rgb(2), rgb(3));
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.showImage(loadImage)
        end

        % Button pushed function: AddLabelBtn
        function AddLabelBtnPushed(app, event)
            % Disable the main app window
            app.AddLabelBtn.Enable = 'off';

            % Create a new modal dialog
            app.newLabel = struct(); % initialize a new candidate label
            
            dialogBox = auxDialogBox(app);
            % wait until label setup complete
            waitfor(dialogBox);
            app.AddLabelBtn.Enable = 'on';
            app.newLabel
            if ~isequal(app.newLabel,struct())
                % request demente for a new label
                % (duplicate names ...)
                % if demente says "ok"
    
                % add a new row to the gridLayout
                app.GridLayout2.RowHeight(end)   = {app.labelSize};
                app.GridLayout2.RowHeight(end+1) = {app.btnSize};
    
                % move the button down
                app.AddLabelBtn.Layout.Row = app.AddLabelBtn.Layout.Row + 1;
    
                lentry = LabelEntry(app.GridLayout2);
                lentry.label = app.newLabel;
                lentry.Layout.Row = app.AddLabelBtn.Layout.Row - 1; % place the label in the row above the moved button
                lentry.Layout.Column = app.AddLabelBtn.Layout.Column; % there is only one column
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.LabelsPanel = uipanel(app.GridLayout);
            app.LabelsPanel.Title = 'Labels';
            app.GridLayout2 = uigridlayout(app.LabelsPanel);
            app.AddLabelBtn = uibutton(app.GridLayout2, 'push');
            app.AddLabelBtn.ButtonPushedFcn = createCallbackFcn(app, @AddLabelBtnPushed, true);
            app.AddLabelBtn.Text = '+';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_3 = uimenu(app.ContextMenu2);
            app.Menu_3.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.UIAxes.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppImg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end