classdef LabelContainer < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Box          matlab.ui.container.GridLayout
        LabelsPanel  matlab.ui.container.Panel
        LabelLayout  matlab.ui.container.GridLayout
        addLabelBtn  matlab.ui.control.Button
    end

    properties (Access = private)
        progressiveIdx = 0;
    end
    
    properties (Access = public)
        currentlySelected = {};
        labels = {};
        newLabel
    end
    
    
    methods (Access = public)


        % we should really do something more than just eliminating the
        % label from the GUI
        % what happens if a user deletes a label midway through labeling?
        % should a user be able to do it? what is the behavior in
        % ImageLabeler?
        function removeLabel(comp, idx)
            % Find the component to remove
            toRemoveIdx = -1;
            for i = 1:length(comp.LabelLayout.Children)
                if isa(comp.LabelLayout.Children(i), 'LabelEntry') && comp.LabelLayout.Children(i).idx == idx
                    toRemoveIdx = i;
                    break;
                end
            end
            
            % If the component is found, remove it
            if toRemoveIdx ~= -1
                % Get the row of the component to remove
                rowToRemove = comp.LabelLayout.Children(toRemoveIdx).Layout.Row;
                comp.labels(rowToRemove) = [];
                % Delete the component
                comp.LabelLayout.Children(toRemoveIdx).delete
                
                % Adjust the layout of the remaining components
                for i = 1:length(comp.LabelLayout.Children)
                    if comp.LabelLayout.Children(i).Layout.Row > rowToRemove
                        comp.LabelLayout.Children(i).Layout.Row = comp.LabelLayout.Children(i).Layout.Row - 1;
                    end
                end
                
                % Remove the corresponding row heights            
                comp.LabelLayout.RowHeight(rowToRemove) = [];
            else
                warning('Component with the specified index was not found.');
            end
        end


        function selectLabel(comp, idx)
            for i = 1:length(comp.LabelLayout.Children)
                if isa(comp.LabelLayout.Children(i), 'LabelEntry') 
                    comp.LabelLayout.Children(i).selected = ...
                           comp.LabelLayout.Children(i).idx == idx;
                    if comp.LabelLayout.Children(i).selected
                        comp.currentlySelected =comp.LabelLayout.Children(i);
                    end
                end
            end
        end


        function out=getLabels(comp)
            out=struct('name',[],'color',[]);
            j=1;
            for i = 1:length(comp.LabelLayout.Children)
                if isa(comp.LabelLayout.Children(i), 'LabelEntry') 
                    out(j).name=comp.LabelLayout.Children(i).label;
                    out(j).color=comp.LabelLayout.Children(i).rgb;
                    j=j+1;
                end
            end
        end

        function addLabelForce(comp, name, rgb)
            % force a new label into the labelcontainer
            comp.newLabel = struct();
            comp.newLabel.('Name') = name;
            comp.newLabel.('Color') = rgb;
            % addNewLabel(comp, name);
            % add a new row to the gridLayout
            comp.LabelLayout.RowHeight(end)   = {30};
            comp.LabelLayout.RowHeight(end+1) = {25};

            % move the button down
            comp.addLabelBtn.Layout.Row = comp.addLabelBtn.Layout.Row + 1;
            
            comp.progressiveIdx = comp.progressiveIdx + 1; 
            lentry = LabelEntry(comp.LabelLayout);
            lentry.labelContainer = comp;
            lentry.idx = comp.progressiveIdx;
            lentry.label = comp.newLabel.Name;
            lentry.rgb= comp.newLabel.Color;
            lentry.Layout.Row = comp.addLabelBtn.Layout.Row - 1; % place the label in the row above the moved button
            lentry.Layout.Column = comp.addLabelBtn.Layout.Column; % there is only one column
            comp.labels{end+1} = comp.newLabel.Name;
            comp.selectLabel(lentry.idx);
        end
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: addLabelBtn
        function addLabelBtnButtonPushed(comp, event)

            % Disable the main app window
            comp.addLabelBtn.Enable = 'off';

            % Create a new modal dialog
            comp.newLabel = struct(); % initialize a new candidate label
            
            dialogBox = auxDialogBox(comp);
            % wait until label setup complete
            waitfor(dialogBox);
            comp.addLabelBtn.Enable = 'on';
            % comp.newLabel
            if ~isequal(comp.newLabel,struct())
                % request demente for a new label
                % (duplicate names ...)
                % if demente says "ok"
                % addNewLabel(comp, comp.newLabel.Name);
    
                % add a new row to the gridLayout
                comp.LabelLayout.RowHeight(end)   = {30};
                comp.LabelLayout.RowHeight(end+1) = {25};
    
                % move the button down
                comp.addLabelBtn.Layout.Row = comp.addLabelBtn.Layout.Row + 1;
                
                comp.progressiveIdx = comp.progressiveIdx + 1; 
                lentry = LabelEntry(comp.LabelLayout);
                lentry.labelContainer = comp;
                lentry.idx = comp.progressiveIdx;
                lentry.label = comp.newLabel.Name;
                lentry.rgb= comp.newLabel.Color;
                lentry.Layout.Row = comp.addLabelBtn.Layout.Row - 1; % place the label in the row above the moved button
                lentry.Layout.Column = comp.addLabelBtn.Layout.Column; % there is only one column
                comp.labels{end+1} = comp.newLabel.Name;
                comp.selectLabel(lentry.idx);
            else
                disp("Empty label")
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 355 560];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.Box = uigridlayout(comp);
            comp.LabelsPanel = uipanel(comp.Box);
            comp.LabelsPanel.Title = 'Labels';
            comp.LabelLayout = uigridlayout(comp.LabelsPanel);
            comp.addLabelBtn = uibutton(comp.LabelLayout, 'push');
            comp.addLabelBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addLabelBtnButtonPushed, true);
            comp.addLabelBtn.Text = '+';
        end
    end
end