classdef demente < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Menu            matlab.ui.container.Menu
        Menu_2          matlab.ui.container.Menu
        Menu2           matlab.ui.container.Menu
        MainLayout      matlab.ui.container.GridLayout
        LabelContainer  LabelContainer
        AuxLayout       matlab.ui.container.GridLayout
        AuxImage1       matlab.ui.control.UIAxes
        AuxImage2       matlab.ui.control.UIAxes
        CentralLayout   matlab.ui.container.GridLayout
        ImageLayout     matlab.ui.container.GridLayout
        LabelButton     matlab.ui.control.Button
        mouseInfoLabel  matlab.ui.control.Label
        MainImage       matlab.ui.control.UIAxes
        ButtonLayout    matlab.ui.container.GridLayout
        Label           matlab.ui.control.Label
        nextButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        currentImage % Description
        imageIdx = 1; % Description
        dataPath = "./data"; % Description
        outputPath = "./data/output_DEMENTE/";
    
        rgbFrames = {};
        lut = [];
        mask_frame = [];
        mask_dict = [];
        query_mask = [];
        total_mask = [];
        dict_results_masks = containers.Map();
        dict_results_labels = containers.Map();
        imgPath = '';
        image = [];
        image_dirty = [];
        done = 0;
    end
    
    methods (Access = private)
        
        function path = getIdxPath(app)
            path = fullfile(app.rgbFrames(app.imageIdx).folder, app.rgbFrames(app.imageIdx).name);
        end
        
        function results = removeLabelFcn(app)
            % not used i guess
        end

        % call this whenever you want to show a new image + masks
        function displayImage(app, rgb)
            % get the masks
            [p,f, ~]=fileparts(rgb);
            maskDir =fullfile(p,f);
            if ~isfolder(maskDir)
                error("masks not present for frame: " + f)
            end


            showImage(app, app.AuxImage1, rgb);
            showImage(app, app.AuxImage2, rgb);
            showImage(app, app.MainImage, rgb);

        end

        function getLUT(app)
            name_and_ext = split(app.rgbFrames(1).name,'.');
            folder_name = name_and_ext{1};
            PATH_FRAME = fullfile(app.rgbFrames(1).folder, folder_name);
            
            app.lut = zeros(size(app.image, 1), size(app.image, 2), 'int64');
            app.mask_frame = zeros(size(app.image), 'uint8');
            app.mask_dict = containers.Map('KeyType', 'int32', 'ValueType', 'any');
            mask_size_list = [];
            
            metadata = readtable(fullfile(PATH_FRAME, 'metadata.csv'));

            for i = 1:height(metadata)
                mask_id = metadata.id(i);
                mask_area = metadata.area(i);
                filename_mask = fullfile(PATH_FRAME, sprintf('%d.png', mask_id));
                mask_cv = imread(filename_mask);
                mask_cv = im2gray(mask_cv);
                app.mask_dict(mask_id) = mask_cv;
                mask_size_list = [mask_size_list; mask_area, mask_id];
            end
            % 
            mask_size_list = sortrows(mask_size_list, -1); % Sort by area descending
            % 
            for i = 1:size(mask_size_list, 1)
                mask_id = mask_size_list(i, 2);
                mask = app.mask_dict(mask_id) == 255;
                app.lut(mask) = mask_id;
                random_color = uint8(randi([0, 255], [1, 3]));
                mask_frame_cat = cat(3, mask, mask, mask);
                app.mask_frame(mask_frame_cat) = repmat(random_color, sum(mask(:)), 1);
            end
        end

        function getResultDict(app)
            disp(app.imgPath);
            app.dict_results_masks(app.imgPath) = {};
            app.dict_results_labels(app.imgPath) = {};
        end

        function zeroQueryMask(app)
            app.query_mask = zeros(size(app.image));
        end

        function zeroTotalMask(app)
            app.total_mask = zeros(size(app.image));
        end

        function save_dataset(app)
            % Define paths
            path_source = fullfile(app.outputPath, 'rgb');
            path_semantic = fullfile(app.outputPath, 'semantic');
            
            % Create directories if they do not exist
            if ~exist(path_source, 'dir')
                mkdir(path_source);
            end
            if ~exist(path_semantic, 'dir')
                mkdir(path_semantic);
            end
            
            % Check which images to skip because they are empty
            skip_lst = {};
            keys = app.dict_results_labels.keys();
            for i = 1:length(keys)
                k = keys{i};
                entry_labels = app.dict_results_labels(k);
                if isempty(entry_labels)
                    skip_lst{end+1} = k;
                end
            end
            
            % Initialize output dictionary
            output_dict.RGB = {};
            % output_dict.BB = {};
            output_dict.Mask = {};
            output_dict.Labels = {};
            
            cnt = 0;
            for i = 1:length(keys)
                k = keys{i};
                path_split = split(k,'/');
                filename = path_split{end};
                name_and_ext = split(filename,'.');
                name = name_and_ext{1};
                if ismember(k, skip_lst)
                    fprintf('skipping %s\n', k);
                    continue;
                end
                
                list_k_bb = {};
                list_k_mask = {};
                list_k_labels = {};
                
                labels = app.dict_results_labels(k);
                masks = app.dict_results_masks(k);
                path_rgb = fullfile(path_source, sprintf('%s.png',name));
                rgb = imread(app.imgPath);
                imwrite(rgb, path_rgb);
                for j = 1:length(entry_labels)
                    lab = labels{j};
                    smask = cell2mat(masks(j));
                    % list_k_bb{end+1} = [entry.bx, entry.by, entry.bw, entry.bh];
                    list_k_labels{end+1} = lab;
                    path_smask = fullfile(path_semantic, sprintf('seg_%d_%s.png', cnt, name));
                    cnt = cnt + 1;
                    imwrite(repmat(smask, [1 1 3]), path_smask);
                    % imshow(smask);
                    list_k_mask{end+1} = path_smask;
                end
                
                % output_dict.BB{end+1} = list_k_bb;
                output_dict.Mask{end+1} = list_k_mask;
                output_dict.Labels{end+1} = list_k_labels;
                output_dict.RGB{end+1} = path_rgb;
            end
            
            % Save output dictionary to JSON file
            jsonStr = jsonencode(output_dict);
            fid = fopen(fullfile(app.outputPath, 'metadata.json'), 'w');
            if fid == -1
                error('Cannot create JSON file');
            end
            fwrite(fid, jsonStr, 'char');
            fclose(fid);
        end

        function dispInBox(app, s)
            app.Label.Text = s;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Fill figure with axes and remove tick labels
            app.MainImage.Position = [0 0 app.UIFigure.Position(3:4)];
            app.MainImage.XLabel.String = '';
            app.MainImage.YLabel.String = '';
            
            axtoolbar(app.AuxImage1,{"pan","zoomin","zoomout",'restoreview'});
            axtoolbar(app.AuxImage2,{"pan","zoomin","zoomout",'restoreview'});
            axtoolbar(app.MainImage,{"pan","zoomin","zoomout",'restoreview'});

            % get rgb folder
            rgbPath = fullfile(app.dataPath,"test_data/");
            % get the list of frames
            app.rgbFrames = dir(fullfile(rgbPath, "*.png"));

            % TODO replace these three calls with a single function
            app.imgPath = fullfile(app.rgbFrames(1).folder, app.rgbFrames(1).name);
            app.image = imread(app.imgPath);
            app.image_dirty = app.image;
            app.displayImage(app.imgPath);
            
            app.getLUT();
            app.zeroQueryMask();
            app.zeroTotalMask();
            app.getResultDict();

            app.mouseInfoLabel.Text = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)',0,0,0,0,0);

        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if app.done
                return
            end
            % save results
            app.save_dataset();

            if app.imageIdx == length(app.rgbFrames)
                app.dispInBox("No more images in the folder.")
                app.done = 1;
                return
            end

            app.imageIdx = app.imageIdx + 1;
            app.imgPath = fullfile(app.rgbFrames(app.imageIdx).folder, app.rgbFrames(app.imageIdx).name);
            app.image = imread(app.imgPath);
            app.image_dirty = app.image;
            app.zeroQueryMask();
            app.zeroTotalMask();
            showImage(app, app.AuxImage1, app.total_mask);
            showImage(app, app.AuxImage2, app.query_mask);
            showImage(app, app.MainImage, app.imgPath);

            % if app.imageIdx == length(app.rgbFrames)
            %     app.nextButton.Enable = false;
            %     return
            % end
            
            % reset stuff
            app.getLUT();
            app.getResultDict();
            
            % if foto finite
            % disable this button
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            % [x,y,rgb] = getMouseXY(app, app.MainImage);
            % 
            % if x == x % is not nan
            %     row = ceil(y);
            %     col = ceil(x);
            %     app.mouseInfoLabel.Text = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)', row, col, rgb(1), rgb(2), rgb(3));
            % end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            % app.dispInBox("ai klikkato!");
            if app.done
                return
            end
            [x,y,rgb] = getMouseXY(app, app.MainImage);
            
            if x == x % is not nan
                row = ceil(y);
                col = ceil(x);
                s = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)', row, col, rgb(1), rgb(2), rgb(3));
                % app.dispInBox(s);
                query_id = app.lut(col, row);
                app.query_mask = app.query_mask | app.mask_dict(query_id);
                app.query_mask = double(app.query_mask(:,:,1));
                showImage(app, app.AuxImage2, app.query_mask);
                imagePath = app.getIdxPath();
                
                mask = logical(app.query_mask);
                mask = repmat(mask, [1, 1, size(app.image, 3)]);
                app.image_dirty(mask) = 0;
                showImage(app, app.MainImage, app.image_dirty);

            else
                % app.dispInBox("ai klikkato ma xò nn nell immagine!!")
            end
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            showImage(app, app.MainImage, loadImage);
        end

        % Button pushed function: LabelButton
        function LabelButtonPushed(app, event)
            if app.done
                return
            end
            selected_label = app.LabelContainer.currentlySelected;
            if selected_label == -1
                app.dispInBox("No label selected.")
                return
            end
            current_cell_array = app.dict_results_masks(app.imgPath);
            current_cell_array{end+1} = app.query_mask;
            app.dict_results_masks(app.imgPath) = current_cell_array;

            current_cell_array = app.dict_results_labels(app.imgPath);
            current_cell_array{end+1} = app.LabelContainer.labels(selected_label);
            app.dict_results_labels(app.imgPath) = current_cell_array;
            s = sprintf("Mask created with label '%s'",char(app.LabelContainer.labels(selected_label)));
            app.dispInBox(s)
            app.total_mask = app.total_mask | app.query_mask;
            app.total_mask = app.total_mask(:,:,1);
            app.zeroQueryMask();
            showImage(app, app.AuxImage1, app.total_mask);
            showImage(app, app.AuxImage2, app.query_mask);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.MainLayout = uigridlayout(app.UIFigure);
            app.CentralLayout = uigridlayout(app.MainLayout);
            app.ButtonLayout = uigridlayout(app.CentralLayout);
            app.nextButton = uibutton(app.ButtonLayout, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = '>';
            app.Label = uilabel(app.ButtonLayout);
            app.Label.Text = ' ';
            app.ImageLayout = uigridlayout(app.CentralLayout);
            app.MainImage = uiaxes(app.ImageLayout);
            zlabel(app.MainImage, 'Z')
            app.MainImage.XTick = [];
            app.MainImage.YTick = [];
            app.mouseInfoLabel = uilabel(app.ImageLayout);
            app.LabelButton = uibutton(app.ImageLayout, 'push');
            app.LabelButton.ButtonPushedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.LabelButton.Text = 'Label';
            app.AuxLayout = uigridlayout(app.MainLayout);
            app.AuxImage2 = uiaxes(app.AuxLayout);
            zlabel(app.AuxImage2, 'Z')
            app.AuxImage2.XTick = [];
            app.AuxImage2.YTick = [];
            app.AuxImage1 = uiaxes(app.AuxLayout);
            zlabel(app.AuxImage1, 'Z')
            app.AuxImage1.XTick = [];
            app.AuxImage1.YTick = [];
            app.LabelContainer = LabelContainer(app.MainLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demente

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end