classdef quak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        SelectinputfolderMenu   matlab.ui.container.Menu
        SelectoutputfolderMenu  matlab.ui.container.Menu
        ShortcutsMenu           matlab.ui.container.Menu
        LabelspacebarMenu       matlab.ui.container.Menu
        NextnMenu               matlab.ui.container.Menu
        UndobMenu               matlab.ui.container.Menu
        MainLayout              matlab.ui.container.GridLayout
        LabelContainer          LabelContainer
        GridLayout              matlab.ui.container.GridLayout
        Label                   matlab.ui.control.Label
        CentralLayout           matlab.ui.container.GridLayout
        ImageLayout             matlab.ui.container.GridLayout
        MainImage               matlab.ui.control.UIAxes
        ButtonLayout            matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        undoButton              matlab.ui.control.Button
        nextButton              matlab.ui.control.Button
        LabelButton             matlab.ui.control.Button
        AuxImage2               matlab.ui.control.UIAxes
        AuxImage1               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        currentImage % Description
        imageIdx = 1; % Description
        dataPath = "./data/input/"; % Description
        outputPath = "./data/results/";

        rgbFrames = {};
        lut = [];
        mask_frame = [];
        mask_dict = [];
        query_mask = [];
        total_mask = [];
        dict_results_masks = containers.Map();
        dict_results_labels = containers.Map();
        dict_results_bb = containers.Map();
        imgPath = '';
        image = [];
        image_dirty = [];
        done = 0;

        HiddenFocusButton matlab.ui.control.Button;
    end
    
    methods (Access = private)
        
        function path = getIdxPath(app)
            path = fullfile(app.rgbFrames(app.imageIdx).folder, app.rgbFrames(app.imageIdx).name);
        end

        % call this whenever you want to show a new image + masks
        function displayImage(app, rgb)
            % get the masks
            [p,f, ~]=fileparts(rgb);
            maskDir =fullfile(p,f);
            if ~isfolder(maskDir)
                error("masks not present for frame: " + f)
            end


            showImage(app, app.AuxImage1, rgb, true);
            showImage(app, app.AuxImage2, rgb, true);
            showImage(app, app.MainImage, rgb, true);

        end

        function getLUT(app)
            name_and_ext = split(app.rgbFrames(app.imageIdx).name,'.');
            folder_name = name_and_ext{1};
            PATH_FRAME = fullfile(app.rgbFrames(1).folder, folder_name);
            
            app.lut = zeros(size(app.image, 1), size(app.image, 2), 'int64');
            app.mask_frame = zeros(size(app.image), 'uint8');
            app.mask_dict = containers.Map('KeyType', 'int32', 'ValueType', 'any');
            mask_size_list = [];
            
            metadata = readtable(fullfile(PATH_FRAME, 'metadata.csv'));

            for i = 1:height(metadata)
                mask_id = metadata.id(i);
                mask_area = metadata.area(i);
                filename_mask = fullfile(PATH_FRAME, sprintf('%d.png', mask_id));
                mask_cv = imread(filename_mask);
                mask_cv = im2gray(mask_cv);
                app.mask_dict(mask_id) = mask_cv;
                mask_size_list = [mask_size_list; mask_area, mask_id];
            end
            % 
            mask_size_list = sortrows(mask_size_list, -1); % Sort by area descending
            % 
            for i = 1:size(mask_size_list, 1)
                mask_id = mask_size_list(i, 2);
                mask = app.mask_dict(mask_id) == 255;
                app.lut(mask) = mask_id;
                random_color = uint8(randi([0, 255], [1, 3]));
                mask_frame_cat = cat(3, mask, mask, mask);
                app.mask_frame(mask_frame_cat) = repmat(random_color, sum(mask(:)), 1);
            end
        end

        function getResultDict(app)
            app.dict_results_masks = containers.Map();
            app.dict_results_labels = containers.Map();
            app.dict_results_bb = containers.Map();
            app.dict_results_masks(app.imgPath) = {};
            app.dict_results_labels(app.imgPath) = {};
            app.dict_results_bb(app.imgPath) = {};
        end

        function zeroQueryMask(app)
            app.query_mask = zeros(size(app.image));
        end

        function zeroTotalMask(app)
            app.total_mask = zeros(size(app.image));
        end

        function save_dataset(app)
            % Define paths
            path_source = fullfile(app.outputPath, 'rgb');
            path_semantic = fullfile(app.outputPath, 'semantic');
            
            % Create directories if they do not exist
            if ~exist(path_source, 'dir')
                mkdir(path_source);
            end
            if ~exist(path_semantic, 'dir')
                mkdir(path_semantic);
            end
            
            % Check which images to skip because they are empty
            skip_lst = {};
            keys = app.dict_results_labels.keys();
            for i = 1:length(keys)
                k = keys{i};
                entry_labels = app.dict_results_labels(k);
                if isempty(entry_labels)
                    skip_lst{end+1} = k;
                end
            end
            
            % Initialize output dictionary
            output_dict.RGB = {};
            output_dict.BB = {};
            output_dict.Mask = {};
            output_dict.Labels = {};

            output_dict.LabelColors =app.LabelContainer.getLabels();


            cnt = 0;
            for i = 1:length(keys)
                k = keys{i};
                path_split = split(k,'/');
                filename = path_split{end};
                name_and_ext = split(filename,'.');
                name = name_and_ext{1};

                if ismember(k, skip_lst)
                    fprintf('skipping %s\n', k);
                    continue;
                end
                
                list_k_bb = {};
                list_k_mask = {};
                list_k_labels = {};
                
                labels = app.dict_results_labels(k);
                masks = app.dict_results_masks(k);
                bbs = app.dict_results_bb(k);
                path_rgb = fullfile(path_source, sprintf('%s.png',name));
                rgb = imread(app.imgPath);
                imwrite(rgb, path_rgb);
                for j = 1:length(entry_labels)
                    lab = labels{j};
                    smask = cell2mat(masks(j));
                    bb = bbs{j};
                    % list_k_bb{end+1} = [entry.bx, entry.by, entry.bw, entry.bh];
                    list_k_labels{end+1} = lab;
                    path_smask = fullfile(path_semantic, sprintf('seg_%d_%s.png', cnt, name));
                    cnt = cnt + 1;
                    imwrite(repmat(smask, [1 1 3]), path_smask);
                    % imshow(smask);
                    list_k_mask{end+1} = path_smask;
                    list_k_bb{end+1} = bb;
                end
                
                output_dict.BB{end+1} = cell2mat(list_k_bb');
                output_dict.Mask{end+1} = list_k_mask;
                output_dict.Labels{end+1} = list_k_labels;
                output_dict.RGB{end+1} = path_rgb;
            end
            
            % Save output dictionary to JSON file
            app.concatenate_json(output_dict);
        end
        
        function concatenate_json(app, new_dict)
            % Read the existing JSON file
            existing_json_path = fullfile(app.outputPath, 'metadata.json');
            fid = fopen(existing_json_path, 'r');
            if fid == -1
                encoded_json = jsonencode(new_dict);
                fid = fopen(existing_json_path, 'w');
                if fid == -1
                    error('Cannot open file for writing: %s', existing_json_path);
                end
                fwrite(fid, encoded_json, 'char');
                fclose(fid);
                return
            end
            raw = fread(fid, inf, '*char');
            str = raw';
            fclose(fid);
        
            % Parse the existing JSON data
            try
                existing_json = jsondecode(str);
            catch
                error('Error parsing existing JSON data. The file may not be valid JSON.');
            end
        
            % Check if 'RGB' key exists in both JSONs
            if ~isfield(existing_json, 'RGB') || ~isfield(new_dict, 'RGB')
                error('Both JSONs must contain an "RGB" key.');
            end
        
            % Concatenate the RGB lists
            updated_json = existing_json;
        
            % Concatenate other fields if they exist in both JSONs
            fields = fieldnames(new_dict);
            for i = 1:length(fields)
                field = fields{i};
                if isequal(field,'BB')
                    bb_existing = existing_json.(field);
                    bb_existing2(:,:) = bb_existing;
                    bb_existing3 = {bb_existing2};
                    bb_new = new_dict.(field);
                    bb_new2 = cell2mat(bb_new');
                    bb_new3 = {bb_new2};
                    updated_json.(field) = cat(1, bb_existing3, bb_new3);
                elseif isfield(existing_json, field) && iscell(existing_json.(field)) && iscell(new_dict.(field))
                    updated_json.(field) = cat(1, existing_json.(field), new_dict.(field)');
                elseif ~isfield(existing_json, field)
                    updated_json.(field) = new_dict.(field);
                end
            end
        
            % Save the updated JSON data back to the file
            try
                encoded_json = jsonencode(updated_json);
                fid = fopen(existing_json_path, 'w');
                if fid == -1
                    error('Cannot open file for writing: %s', existing_json_path);
                end
                fwrite(fid, encoded_json, 'char');
                fclose(fid);
            catch ME
                if fid ~= -1
                    fclose(fid);
                end
                rethrow(ME);
            end
        end


        function dispInBox(app, s)
            app.Label.Text = s;
        end
                
        function windowKeyPressCallback(app, src, event)
            if strcmp(event.Key, 'b')
                undoButtonPushed(app, event);
            elseif strcmp(event.Key, 'space')
                LabelButtonPushed(app, event);
            elseif strcmp(event.Key, 'n')
                nextButtonPushed(app, event);
            end
        end

        function check_output_folder(app)
            % Check if metadata.json exists in the outputPath folder
            jsonFilePath = fullfile(app.outputPath, 'metadata.json');
            if isfile(jsonFilePath)
                % Read and parse the JSON file
                jsonData = fileread(jsonFilePath);
                data = jsondecode(jsonData);
                
                % Check if "RGB" key exists in the JSON data
                if isfield(data, 'RGB')
                    % Extract the list of paths from the JSON data
                    rgbPaths = data.RGB;
                    if isempty(rgbPaths)
                        return
                    end
                    [~, rgbFilenames, rgbExt] = cellfun(@fileparts, rgbPaths, 'UniformOutput', false);
                    rgbFilenames = strcat(rgbFilenames, rgbExt); % Combine name and extension
            
                    % Compare the list of paths to the list of names in app.rgbFrames
                    rgbFrameNames = {app.rgbFrames.name};
                    commonPaths = intersect(rgbFilenames, rgbFrameNames);
                    % If there are common paths, create a warning popup
                    if ~isempty(commonPaths)
                        app.createWarningPopup();
                    else
                        app.done = false;
                    end
                end
            end
        end
        
        function createWarningPopup(app)
            % Create a figure for the popup window
            f = uifigure('Name', 'Warning', 'Position', [100 100 500 150]);
            movegui(f,'center');
            % Create a label with the warning message
            lblText = ['WARNING', newline, ...
                       'The output folder already contains results for images with the same names. ', newline, ...
                       'Select how to procede.', newline, ...
                       'If this is not desired, change output folder from Menu->Select output folder.'];
            
            lbl = uilabel(f, ...
                'Text', lblText, ...
                'Position', [10 40 480 90], ...
                'FontSize', 12, ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'top', ...
                'BackgroundColor', 'none');
            
            % Create an OK button to close the popup
            btn = uibutton(f, ...
                'Text', 'Empty results folder.', ...
                'Position', [50 20 175 30], ...
                'FontSize', 12, ...
                'ButtonPushedFcn', @(btn, event) app.emptyResultsFolder(f));

            btn = uibutton(f, ...
                'Text', 'Skip already processed.', ...
                'Position', [275 20 175 30], ...
                'FontSize', 12, ...
                'ButtonPushedFcn', @(btn, event) app.skipProcessedFiles(f));
        end

        function emptyResultsFolder(app, f)
            % Get a list of all files and folders in directory
            file_list = dir(fullfile(app.outputPath, '*'));
            
            % Loop through each item
            for i = 1:length(file_list)
                item = file_list(i);
                
                % Skip '.' and '..' directories
                if strcmp(item.name, '.') || strcmp(item.name, '..')
                    continue;
                end
                
                item_path = fullfile(app.outputPath, item.name);
                
                if item.isdir
                    % If it's a directory, use rmdir with 's' option
                    rmdir(item_path, 's');
                else
                    % If it's a file, delete it
                    delete(item_path);
                end
            end
            close(f);
            app.done = false;
        end

        function skipProcessedFiles(app, f)
            existing_json_path = fullfile(app.outputPath, 'metadata.json');
            fid = fopen(existing_json_path, 'r');
            if fid == -1
                encoded_json = jsonencode(new_dict);
                fid = fopen(existing_json_path, 'w');
                if fid == -1
                    error('Cannot open file for writing: %s', existing_json_path);
                end
                fwrite(fid, encoded_json, 'char');
                fclose(fid);
                return
            end
            raw = fread(fid, inf, '*char');
            str = raw';
            fclose(fid);

            existing_json = jsondecode(str);
            to_remove = [];
            for i = 1:length(app.rgbFrames)
                file= fullfile(app.outputPath,'rgb/', app.rgbFrames(i).name);
                if any(contains(existing_json.("RGB"), file))
                    to_remove(end+1) = i;
                end
            end
            app.rgbFrames(to_remove)=[];
            
            % labels loaded in "app.loadlabels"
            % try
            %     for i=1:length(existing_json.LabelColors)
            %         app.LabelContainer.addLabelForce(existing_json.LabelColors(i).name, existing_json.LabelColors(i).color);
            %     end
            % catch e
            %     disp('there are no labels to restore. Probably the metadata file is using an old version of QuaK');
            % end


            if isempty(app.rgbFrames)
                app.done = true;
                app.dispInBox("All images in the input folder are already processed.")

                close(f);
                return;
            end


            % TODO replace these three calls with a single function
            app.imgPath = fullfile(app.rgbFrames(1).folder, app.rgbFrames(1).name);
            app.image = imread(app.imgPath);
            app.image_dirty = app.image;
            app.displayImage(app.imgPath);
            app.getResultDict();

            close(f);
            app.done = false;
        end

        function bbox = find_bbox(app)
            % Get bounding box
            props = regionprops(app.query_mask, 'BoundingBox');
            
            % Extract bounding box (assuming there's only one region)
            if ~isempty(props)
                bbox = props(1).BoundingBox; % [x, y, width, height]
                [imgHeight, imgWidth, ~] = size(app.image);
                center_x = (bbox(1) + bbox(3)/2)/imgWidth;
                center_y = (bbox(2) + bbox(4)/2)/imgHeight;
                width = bbox(3)/imgWidth;
                height = bbox(4)/imgHeight;
            
                bbox = [center_x, center_y, width, height];
            else
                bbox = [];
            end
        end
        
        function draw_bbox(app, bbox)
            if isempty(bbox)
                return
            end
            [imgHeight, imgWidth, ~] = size(app.image_dirty);

            % Denormalize the bounding box values
            x_center = bbox(1) * imgWidth;
            y_center = bbox(2) * imgHeight;
            boxWidth = bbox(3) * imgWidth;
            boxHeight = bbox(4) * imgHeight;
        
            % Calculate the top-left corner of the bounding box
            x_min = round(x_center - boxWidth / 2);
            y_min = round(y_center - boxHeight / 2);
        
            % Calculate the bottom-right corner of the bounding box
            x_max = round(x_min + boxWidth);
            y_max = round(y_min + boxHeight);
        
            % Ensure the bounding box coordinates are within image boundaries
            x_min = max(1, min(x_min, imgWidth));
            y_min = max(1, min(y_min, imgHeight));
            x_max = max(1, min(x_max, imgWidth));
            y_max = max(1, min(y_max, imgHeight));
        
            % Draw the bounding box
            if size(app.image_dirty, 3) == 3
                % RGB image
                app.image_dirty(y_min:y_max, x_min:x_min+1, 1:2) = 255; % Left line
                app.image_dirty(y_min:y_max, x_max:x_max+1, 1:2) = 255; % Right line
                app.image_dirty(y_min:y_min+1, x_min:x_max, 1:2) = 255; % Top line
                app.image_dirty(y_max:y_max+1, x_min:x_max, 1:2) = 255; % Bottom line
                app.image_dirty(y_min:y_max, x_min:x_min+1, 3) = 0; % Left line
                app.image_dirty(y_min:y_max, x_max:x_max+1, 3) = 0; % Right line
                app.image_dirty(y_min:y_min+1, x_min:x_max, 3) = 0; % Top line
                app.image_dirty(y_max:y_max+1, x_min:x_max, 3) = 0; % Bottom line
            end
        end

        function loadLabels(app)
            path = fullfile(app.dataPath, 'labels.json');
            if isfile(path)
                jsonText = fileread(path);
                labelsStruct = jsondecode(jsonText);
                for i = 1:length(labelsStruct.names)
                    name = labelsStruct.names(i);
                    rgb = labelsStruct.colors(i,:);
                    app.LabelContainer.addLabelForce(name, rgb);
                end
            else
                existing_json_path = fullfile(app.outputPath, 'metadata.json');
                fid = fopen(existing_json_path, 'r');
                if fid == -1
                    encoded_json = jsonencode(new_dict);
                    fid = fopen(existing_json_path, 'w');
                    if fid == -1
                        error('Cannot open file for writing: %s', existing_json_path);
                    end
                    fwrite(fid, encoded_json, 'char');
                    fclose(fid);
                    return
                end
                raw = fread(fid, inf, '*char');
                str = raw';
                fclose(fid);
        
                existing_json = jsondecode(str);
                try
                    for i=1:length(existing_json.LabelColors)
                        app.LabelContainer.addLabelForce(existing_json.LabelColors(i).name, existing_json.LabelColors(i).color);
                    end
                catch e
                    e
                    disp('There are no labels to restore. Probably the metadata file is using an old version of QuaK');
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Fill figure with axes and remove tick labels
            app.done = false;            % try
            %     for i=1:length(existing_json.LabelColors)
            %         app.LabelContainer.addLabelForce(existing_json.LabelColors(i).name, existing_json.LabelColors(i).color);
            %     end
            % catch e
            %     disp('there are no labels to restore. Probably the metadata file is using an old version of QuaK');
            % end
            app.MainImage.Position = [0 0 app.UIFigure.Position(3:4)];
            app.MainImage.XLabel.String = '';
            app.MainImage.YLabel.String = '';
            
            axtoolbar(app.AuxImage1,{"pan","zoomin","zoomout",'restoreview'});
            axtoolbar(app.AuxImage2,{"pan","zoomin","zoomout",'restoreview'});
            axtoolbar(app.MainImage,{"pan","zoomin","zoomout",'restoreview'});

            
            % select input and output folder
            app.SelectinputfolderMenuSelected();
            app.SelectoutputfolderMenuSelected();

            % get rgb folder
            rgbPath = app.dataPath;
            % get the list of frames
            app.rgbFrames = dir(fullfile(rgbPath, "*.png"));
            % app.check_output_folder();

            app.imgPath = fullfile(app.rgbFrames(1).folder, app.rgbFrames(1).name);
            app.image = imread(app.imgPath);
            app.image_dirty = app.image;
            app.displayImage(app.imgPath);
            
            app.loadLabels();

            app.getLUT();
            app.zeroQueryMask();
            app.zeroTotalMask();
            app.getResultDict();
            
            app.UIFigure.WindowKeyPressFcn = @(src, event) windowKeyPressCallback(app, src, event);
            % app.mouseInfoLabel.Text = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)',0,0,0,0,0);

        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if app.done
                return
            end
            % save results
            app.save_dataset();

            if app.imageIdx == length(app.rgbFrames)
                app.dispInBox("No more images in the folder.");
                app.done = 1;
                return
            end
            app.dispInBox("Wait....");

            app.imageIdx = app.imageIdx + 1;
            app.imgPath = fullfile(app.rgbFrames(app.imageIdx).folder, app.rgbFrames(app.imageIdx).name);
            app.image = imread(app.imgPath);
            app.image_dirty = app.image;
            app.zeroQueryMask();
            app.zeroTotalMask();
            showImage(app, app.AuxImage1, app.total_mask, false);
            showImage(app, app.AuxImage2, app.query_mask, false);
            showImage(app, app.MainImage, app.imgPath, false);

            % if app.imageIdx == length(app.rgbFrames)
            %     app.nextButton.Enable = false;
            %     return
            % end
            
            % reset stuff
            app.getLUT();
            app.getResultDict();
            % if foto finite
            % disable this button
            app.dispInBox("");
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            % [x,y,rgb] = getMouseXY(app, app.MainImage);
            % 
            % if x == x % is not nan
            %     row = ceil(y);
            %     col = ceil(x);
            %     app.mouseInfoLabel.Text = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)', row, col, rgb(1), rgb(2), rgb(3));
            % end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            % app.dispInBox("ai klikkato!");
            if app.done
                return
            end
            [x,y,rgb] = getMouseXY(app, app.MainImage);
            if x == x % is not nan
                row = ceil(y);
                col = ceil(x);
                
                % app.dispInBox(s);
                query_id = app.lut(col, row);
                app.query_mask = app.query_mask | app.mask_dict(query_id);
                app.query_mask = double(app.query_mask(:,:,1));

                showImage(app, app.AuxImage2, app.query_mask, false);
                mask = logical(app.query_mask);
                mask = repmat(mask, [1, 1, size(app.image, 3)]);
                if isempty(app.LabelContainer.currentlySelected)
                    app.image_dirty(mask) = 0;
                else
                    app.image_dirty = app.image;
                    % Assuming app.image_dirty is of size (M, N, 3)
                    redChannel = app.image_dirty(:,:,1);
                    greenChannel = app.image_dirty(:,:,2);
                    blueChannel = app.image_dirty(:,:,3);
                    
                    % Apply the mask to each channel separately
                    redChannel(mask(:,:,1)) = app.LabelContainer.currentlySelected.rgb(1)*255;
                    greenChannel(mask(:,:,1)) = app.LabelContainer.currentlySelected.rgb(2)*255;
                    blueChannel(mask(:,:,1)) = app.LabelContainer.currentlySelected.rgb(3)*255;
                    
                    % Combine the channels back into the RGB image
                    app.image_dirty(:,:,1) = redChannel;
                    app.image_dirty(:,:,2) = greenChannel;
                    app.image_dirty(:,:,3) = blueChannel;
                    
                    bbox = app.find_bbox();
                    app.draw_bbox(bbox);
                end
                showImage(app, app.MainImage, app.image_dirty, false);
            else
                % app.dispInBox("ai klikkato ma xò nn nell immagine!!")
            end
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            % showImage(app, app.MainImage, loadImage);
        end

        % Button pushed function: LabelButton
        function LabelButtonPushed(app, event)
            if app.done
                return
            end
            selected_label = app.LabelContainer.currentlySelected;
            if isempty(selected_label)
                app.dispInBox("No label selected.");
                return
            end
            if sum(sum(app.query_mask))==0
                app.dispInBox("Empty selection.");
                return
            end
            current_cell_array = app.dict_results_masks(app.imgPath);
            current_cell_array{end+1} = app.query_mask;
            app.dict_results_masks(app.imgPath) = current_cell_array;

            current_cell_array = app.dict_results_labels(app.imgPath);
            current_cell_array{end+1} = selected_label.label;
            app.dict_results_labels(app.imgPath) = current_cell_array;

            current_cell_array = app.dict_results_bb(app.imgPath);
            current_cell_array{end+1} = app.find_bbox();
            app.dict_results_bb(app.imgPath) = current_cell_array;

            s = sprintf("Mask created with label '%s'",char(selected_label.label));
            app.dispInBox(s);
            app.total_mask = app.total_mask | app.query_mask;
            app.total_mask = app.total_mask(:,:,1);
            app.zeroQueryMask();
            app.image_dirty = app.image;
            showImage(app, app.AuxImage1, app.total_mask, false);
            showImage(app, app.AuxImage2, app.query_mask, false);
            showImage(app, app.MainImage, app.image_dirty, false);
        end

        % Button pushed function: undoButton
        function undoButtonPushed(app, event)
            if sum(sum(app.query_mask)) ~= 0
                app.zeroQueryMask();
                app.image_dirty = app.image;
                showImage(app, app.AuxImage1, app.total_mask, false);
                showImage(app, app.AuxImage2, app.query_mask, false);
                showImage(app, app.MainImage, app.image, false);
            else
                % Get the current masks and labels
                current_masks = app.dict_results_masks(app.imgPath);
                current_labels = app.dict_results_labels(app.imgPath);
                current_bb = app.dict_results_bb(app.imgPath);
                
                % Remove the last added mask and label
                if ~isempty(current_masks)
                    current_masks(end) = [];
                    app.dict_results_masks(app.imgPath) = current_masks;
                else
                    return
                end
                
                if ~isempty(current_labels)
                    current_labels(end) = [];
                    app.dict_results_labels(app.imgPath) = current_labels;
                end

                if ~isempty(current_bb)
                    current_bb(end) = [];
                    app.dict_results_bb(app.imgPath) = current_bb;
                end
                
                % Recreate the total_mask without the last added mask
                if isempty(current_masks)
                    app.total_mask = false(size(app.query_mask, 1), size(app.query_mask, 2));
                else
                    app.total_mask = false(size(app.total_mask));
                    app.image_dirty = app.image;
                    for i = 1:length(current_masks)
                        app.total_mask = app.total_mask | current_masks{i};
                    end
                end
                
                app.image_dirty = app.image;
                % Show the updated images
                showImage(app, app.AuxImage1, app.total_mask, false);
                showImage(app, app.AuxImage2, app.query_mask, false);
                showImage(app, app.MainImage, app.image, false);
            end
        end

        % Menu selected function: SelectinputfolderMenu
        function SelectinputfolderMenuSelected(app, event)
            % Open file explorer dialog for folder selection
            selectedFolder = uigetdir(".", "Select Input path");
            
            % Check if user canceled the selection
            if selectedFolder == 0
                app.dispInBox('Folder selection canceled.');
                return;
            end
            
            % Use the selected folder (example: display the selected folder path)
            app.dispInBox(['Selected folder: ', selectedFolder]);
            app.dataPath = selectedFolder;

            % get rgb folder
            rgbPath = app.dataPath;
            % get the list of frames
            app.rgbFrames = dir(fullfile(rgbPath, "*.png"));

            % TODO replace these three calls with a single function
            app.imgPath = fullfile(app.rgbFrames(1).folder, app.rgbFrames(1).name);
            app.image = imread(app.imgPath);
            app.image_dirty = app.image;
            app.displayImage(app.imgPath);
            % app.check_output_folder();
        end

        % Menu selected function: SelectoutputfolderMenu
        function SelectoutputfolderMenuSelected(app, event)
            % Open file explorer dialog for folder selection
            selectedFolder = uigetdir(".", "Select Output path");
            
            % Check if user canceled the selection
            if selectedFolder == 0
                app.dispInBox('Folder selection canceled.');
                return;
            end
            
            % Use the selected folder (example: display the selected folder path)
            app.dispInBox(['Selected output folder: ', selectedFolder]);
            app.outputPath = selectedFolder;
            app.check_output_folder();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = 'Menu';
            app.SelectinputfolderMenu = uimenu(app.Menu);
            app.SelectinputfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectinputfolderMenuSelected, true);
            app.SelectinputfolderMenu.Text = 'Select input folder';
            app.SelectoutputfolderMenu = uimenu(app.Menu);
            app.SelectoutputfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectoutputfolderMenuSelected, true);
            app.SelectoutputfolderMenu.Text = 'Select output folder';
            app.ShortcutsMenu = uimenu(app.UIFigure);
            app.ShortcutsMenu.Text = 'Shortcuts';
            app.LabelspacebarMenu = uimenu(app.ShortcutsMenu);
            app.LabelspacebarMenu.Text = 'Label: spacebar';
            app.NextnMenu = uimenu(app.ShortcutsMenu);
            app.NextnMenu.Text = 'Next: n';
            app.UndobMenu = uimenu(app.ShortcutsMenu);
            app.UndobMenu.Text = 'Undo: b';
            app.MainLayout = uigridlayout(app.UIFigure);
            app.AuxImage1 = uiaxes(app.MainLayout);
            title(app.AuxImage1, 'Total selection')
            zlabel(app.AuxImage1, 'Z')
            app.AuxImage1.XTick = [];
            app.AuxImage1.YTick = [];
            app.AuxImage1.ZTick = [];
            app.AuxImage2 = uiaxes(app.MainLayout);
            title(app.AuxImage2, 'Current selection')
            zlabel(app.AuxImage2, 'Z')
            app.AuxImage2.XTick = [];
            app.AuxImage2.YTick = [];
            app.AuxImage2.ZTick = [];
            app.CentralLayout = uigridlayout(app.MainLayout);
            app.ButtonLayout = uigridlayout(app.CentralLayout);
            app.LabelButton = uibutton(app.ButtonLayout, 'push');
            app.LabelButton.ButtonPushedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.LabelButton.Text = 'Label';
            app.GridLayout2 = uigridlayout(app.ButtonLayout);
            app.nextButton = uibutton(app.GridLayout2, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'Next Image >';
            app.undoButton = uibutton(app.GridLayout2, 'push');
            app.undoButton.ButtonPushedFcn = createCallbackFcn(app, @undoButtonPushed, true);
            app.undoButton.Text = '< Undo';
            app.ImageLayout = uigridlayout(app.CentralLayout);
            app.MainImage = uiaxes(app.ImageLayout);
            title(app.MainImage, 'Current image')
            zlabel(app.MainImage, 'Z')
            app.MainImage.XTick = [];
            app.MainImage.YTick = [];
            app.MainImage.ZTick = [];
            app.GridLayout = uigridlayout(app.MainLayout);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.LabelContainer = LabelContainer(app.MainLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quak

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end